classdef HW2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        SaveAsMenu          matlab.ui.container.Menu
        ExitMenu            matlab.ui.container.Menu
        ProcessMenu         matlab.ui.container.Menu
        BinarizationMenu    matlab.ui.container.Menu
        SaltandpepperMenu   matlab.ui.container.Menu
        GaussianfilterMenu  matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        Button              matlab.ui.control.Button
        Label               matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        Image_2             matlab.ui.control.Image
        Label_2             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vidObj  % 建立視訊物件
        p = 1 % 建立Process選項 預設為1
        y % Description
        fs % Description
    end
    
    methods (Access = private)
        
        %影像處理的function
        function results = func(app, im)
            switch app.p
                %二值化
                case 1
                    results = rgb2gray(im); %先轉灰階
                    results(find(results>=128)) = 256; % >=128的設成256
                    results(find(results<128)) = 1; % <128的設成1
                    results = repmat(results, [1 1 3]); %因為image只能呈現三通道故我們使用repmat讓三通道都是單通道的灰階值
                    app.Label_2.Text = 'Binarization'; %設定label2的text
                %加胡椒鹽雜訊
                case 2
                    results = im; %將原圖先複製給results
                    [width height zz] = size(im); %讀取圖片大小
                    for i=1:3000 %加入3000個雜訊
                        xx = randi(width); %取出隨機的x
                        yy = randi(height); %取出隨機的y
                        results(xx, yy, :) = [255 255 255]; %在x y 座標加入白點
                    end
                    app.Label_2.Text = 'Salt & pepper'; %設定label2的text
                %高斯模糊
                case 3
                      results = imgaussfilt(im, 2); %高斯模糊的function
                      app.Label_2.Text = 'Gaussian filter'; %設定label2的text
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.vidObj = VideoReader("xylophone.mpg"); %先讀取預設的影片的影像
            [app.y, app.fs] = audioread("xylophone.mpg"); %讀取預設影片的聲音
            im = read(app.vidObj, 1); %讀取影片的第一個frame
            app.Image.ImageSource = im; %設定左邊的圖框為未處理的frame
            app.Image_2.ImageSource = func(app, im); %設定右邊的圖框為處理過的frame
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {461, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app); %關閉視窗
        end

        % Menu selected function: ProcessMenu
        function ProcessMenuSelected(app, event)
            
        end

        % Menu selected function: BinarizationMenu
        function BinarizationMenuSelected(app, event)
            im = app.Image.ImageSource; %讀取圖片
            app.p = 1; %設定process為1
            app.Image_2.ImageSource = func(app, im); %設定右邊為處理過的圖片
        end

        % Menu selected function: SaltandpepperMenu
        function SaltandpepperMenuSelected(app, event)
            im = app.Image.ImageSource; %讀取圖片
            app.p = 2; %設定process為2
            app.Image_2.ImageSource = func(app, im); %設定右邊為處理過的圖片
        end

        % Menu selected function: GaussianfilterMenu
        function GaussianfilterMenuSelected(app, event)
            im = app.Image.ImageSource; %讀取圖片
            app.p = 3; %設定process為3
            app.Image_2.ImageSource = func(app, im); %設定右邊為處理過的圖片
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Icon = 'pause.jpg'; %設定button的圖片改成暫停圖片
            i = 1;
            sound(app.y, app.fs*0.8); %將聲音的頻率*0.8，聽起來較不會有延遲
            while hasFrame(app.vidObj) %寫while迴圈直到沒有frame可以讀
                vidFrame = read(app.vidObj, i); %讀第i張frame
                i = i + 1; %i去做累加
                app.Image.ImageSource = vidFrame; %左邊的圖片設定成未處理的frame
                app.Image_2.ImageSource = func(app, vidFrame); %右邊的圖片設定成處理過的frame
                pause(1/app.vidObj.FrameRate); %暫停影片頻率倒數的時間
                app.Label.Text = num2str(app.vidObj.CurrentTime); %設定label為影片時間
            end
            app.vidObj.CurrentTime = 0; %讓時間歸零
            app.Button.Icon = "down.jpg"; %設定button的圖片為開始圖片
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mpg';'*.mp4'}); %利用uigetfile讀取影片檔
            app.vidObj = VideoReader([pathname filename]); %將vidObj設定成讀進來的影片
            [app.y, app.fs] = audioread([pathname filename]); %讀取讀進來影片的聲音
            im = read(app.vidObj, 1); %讀取第一張frame
            app.Image.ImageSource = im; %設定左邊圖片為未處理的frame
            app.Image_2.ImageSource = func(app, im); %設定右邊圖片為處理過的frame
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname] = uiputfile({'*.mp4';'*.mpg'}); %利用uiputfile設定要寫入的檔案
            vidObj2 = VideoWriter([pathname filename], 'MPEG-4'); %將要寫入的檔案設成vidObj2
            open(vidObj2); %開啟vidObj2
            i = 0; %設定wait bar的初始值
            h=waitbar(i,'please wait'); %waitbar
            while hasFrame(app.vidObj) %讀到沒有frame為止
                vidFrame = readFrame(app.vidObj); %讀frame
                current_frame = func(app, vidFrame); %對frame做處理
                writeVideo(vidObj2, current_frame); %寫入vidObj2
                waitbar(i/100, h); %設定wait bar持續累加
                i = i + 1; %i做累加
            end
            delete(h); %刪掉wait bar
            close(vidObj2); %關掉vidObj2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuSelected, true);
            app.ProcessMenu.Text = 'Process';
            app.BinarizationMenu = uimenu(app.ProcessMenu);
            app.BinarizationMenu.MenuSelectedFcn = createCallbackFcn(app, @BinarizationMenuSelected, true);
            app.BinarizationMenu.Text = 'Binarization';
            app.SaltandpepperMenu = uimenu(app.ProcessMenu);
            app.SaltandpepperMenu.MenuSelectedFcn = createCallbackFcn(app, @SaltandpepperMenuSelected, true);
            app.SaltandpepperMenu.Text = 'Salt and pepper';
            app.GaussianfilterMenu = uimenu(app.ProcessMenu);
            app.GaussianfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianfilterMenuSelected, true);
            app.GaussianfilterMenu.Text = 'Gaussian filter';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'down.jpg';
            app.Button.Text = '';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Label_2 = uilabel(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end